classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FunctionEditField       matlab.ui.control.EditField
        FunctionEditFieldLabel  matlab.ui.control.Label
        MinimizeDropDown        matlab.ui.control.DropDown
        MinimizeDropDownLabel   matlab.ui.control.Label
        MaxvalSpinner           matlab.ui.control.Spinner
        MaxvalSpinnerLabel      matlab.ui.control.Label
        MinvalSpinner           matlab.ui.control.Spinner
        MinvalSpinnerLabel      matlab.ui.control.Label
        RunButton               matlab.ui.control.Button
        MutationDropDown        matlab.ui.control.DropDown
        MutationDropDownLabel   matlab.ui.control.Label
        PopsizeSpinner          matlab.ui.control.Spinner
        PopsizeSpinnerLabel     matlab.ui.control.Label
        CrossoverDropDown       matlab.ui.control.DropDown
        CrossoverDropDownLabel  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        gen_alg % Binary gen alg class
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.gen_alg = gen_alg_bin();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            cla(app.UIAxes, 'reset')

            % Get ga params
            current_min = app.MinvalSpinner.Value;
            current_max = app.MaxvalSpinner.Value;
            current_minimize = strcmp(app.MinimizeDropDown.Value, 'mini');
            func_str = app.FunctionEditField.Value;
            current_func = str2func(func_str);

            app.gen_alg.optimize( ...
                current_func, ...
                current_min, ...
                current_max, ...
                current_minimize, ...
                app.UIAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CrossoverDropDownLabel = uilabel(app.UIFigure);
            app.CrossoverDropDownLabel.Text = 'Crossover';
            app.CrossoverDropDown = uidropdown(app.UIFigure);
            app.PopsizeSpinnerLabel = uilabel(app.UIFigure);
            app.PopsizeSpinnerLabel.Text = 'Pop size';
            app.PopsizeSpinner = uispinner(app.UIFigure);
            app.MutationDropDownLabel = uilabel(app.UIFigure);
            app.MutationDropDownLabel.Text = 'Mutation';
            app.MutationDropDown = uidropdown(app.UIFigure);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.MinvalSpinnerLabel = uilabel(app.UIFigure);
            app.MinvalSpinnerLabel.Text = 'Min val';
            app.MinvalSpinner = uispinner(app.UIFigure);
            app.MinvalSpinner.Value = -1;
            app.MaxvalSpinnerLabel = uilabel(app.UIFigure);
            app.MaxvalSpinnerLabel.Text = 'Max val';
            app.MaxvalSpinner = uispinner(app.UIFigure);
            app.MaxvalSpinner.Value = 28;
            app.MinimizeDropDownLabel = uilabel(app.UIFigure);
            app.MinimizeDropDownLabel.Text = 'Minimize';
            app.MinimizeDropDown = uidropdown(app.UIFigure);
            app.MinimizeDropDown.Items = {'True', 'False'};
            app.MinimizeDropDown.ItemsData = {'mini', 'maxi'};
            app.MinimizeDropDown.Value = 'maxi';
            app.FunctionEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end